classdef ea_calculate_unifiedmapping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CalculateButton              matlab.ui.control.Button
        FiberfilteringCalculationMethodSwitch  matlab.ui.control.Switch
        FiberfilteringCalculationMethodSwitchLabel  matlab.ui.control.Label
        CalculateinDropDown          matlab.ui.control.DropDown
        CalculateinDropDownLabel     matlab.ui.control.Label
        ResolutionDropDown           matlab.ui.control.DropDown
        ResolutionDropDownLabel      matlab.ui.control.Label
        VmLabel                      matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditField_3Label    matlab.ui.control.Label
        ConnectomeDropDown           matlab.ui.control.DropDown
        ConnectomeDropDown_2Label    matlab.ui.control.Label
        BiophysicalModelFamilyButtonGroup  matlab.ui.container.ButtonGroup
        PathwayActionModelingButton  matlab.ui.control.RadioButton
        StimulationVolumesButton     matlab.ui.control.RadioButton
        ToolsButtonGroup             matlab.ui.container.ButtonGroup
        NetworkmappingButton         matlab.ui.control.RadioButton
        FiberfilteringButton         matlab.ui.control.RadioButton
        SweetspotButton              matlab.ui.control.RadioButton
        CalculationSettingsPanel     matlab.ui.container.Panel
        mmLabel                      matlab.ui.control.Label
        SSResolutionEditField        matlab.ui.control.NumericEditField
        SSResolutionEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        explorerobj % tractset like object which will save the calculate settings!
    end
    
    
    methods (Access = public)
        
        function results = enable_rules(app)
            selectedTool = app.ToolsButtonGroup.SelectedObject;
            if strcmp(selectedTool.Text,'Sweetspot')
                app.ConnectomeDropDown_2Label.Enable = 'off';
                app.ConnectomeDropDown.Enable = 'off';
                app.ResolutionDropDownLabel.Visible = 'off';
                app.ResolutionDropDown.Visible = 'off';
                app.SSResolutionEditField.Visible = 'on';
                app.SSResolutionEditFieldLabel.Visible = 'on';
                app.CalculateinDropDownLabel.Enable = 'off';
                app.CalculateinDropDown.Enable = 'off';
                app.FiberfilteringCalculationMethodSwitch.Enable = 'off';
                app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'off';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
                app.PathwayActionModelingButton.Enable = 'off';
                app.StimulationVolumesButton.Enable = 'off';
                app.ThresholdEditField.Enable = 'off';
                app.ThresholdEditField_3Label.Enable = 'off';
            elseif strcmp(selectedTool.Text,'Fiberfiltering')
                app.ConnectomeDropDown_2Label.Enable = 'on';
                app.ConnectomeDropDown.Enable = 'on';
                [mdl,sf]=ea_genmodlist;
                dmri_connectomenames = mdl(sf==1);
                dmri_mtconnectomenames = mdl(sf==3);
                app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
                app.ResolutionDropDownLabel.Visible = 'off';
                app.ResolutionDropDown.Visible = 'off';
                app.SSResolutionEditField.Visible = 'off';
                app.SSResolutionEditFieldLabel.Visible = 'off';
                app.CalculateinDropDownLabel.Enable = 'on';
                app.CalculateinDropDown.Enable = 'on';
                app.FiberfilteringCalculationMethodSwitch.Enable = 'on';
                app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'on';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'on';
                app.PathwayActionModelingButton.Enable = 'on';
                app.StimulationVolumesButton.Enable = 'on';
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdEditField_3Label.Enable = 'on';
            elseif strcmp(selectedTool.Text,'Networkmapping')
                app.ConnectomeDropDown_2Label.Enable = 'on';
                app.ConnectomeDropDown.Enable = 'on';
                [mdl,sf]=ea_genmodlist;
                app.ConnectomeDropDown.Items=mdl;
                [~,ix]=ismember(app.ConnectomeDropDown.Value,mdl);
                switch sf(ix)
                    case 1 % structural connectome selected
                        app.ResolutionDropDown.Visible = 'on';
                        app.ResolutionDropDownLabel.Visible = 'on';
                    case 2 % functional connectome selected
                        app.ResolutionDropDown.Visible = 'off';
                        app.ResolutionDropDown.Value = '2 mm';
                end
                app.SSResolutionEditField.Visible = 'off';
                app.SSResolutionEditFieldLabel.Visible = 'off';
                app.CalculateinDropDownLabel.Enable = 'off';
                app.CalculateinDropDown.Enable = 'off';
                app.FiberfilteringCalculationMethodSwitch.Enable = 'off';
                app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'off';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
                app.PathwayActionModelingButton.Enable = 'off';
                app.StimulationVolumesButton.Enable = 'off';
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdEditField_3Label.Enable = 'on';
            end
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, explorer)
            if ~exist('explorer','var')
                ea_error('Something went wrong! try again!');
            else
                app.explorerobj = explorer;
            end
            app.CalculateButton.BackgroundColor = [0 1 0];
            %check if something has been saved
            app.UIFigure.Name = 'Calculate Settings';
            initialize_settings(app)
            function initialize_settings(app)
                %tools button group
                if app.explorerobj.calcsettings.selectedTool == 1
                    app.SweetspotButton.Value = 1;
                elseif app.explorerobj.calcsettings.selectedTool == 2
                    app.FiberfilteringButton.Value = 1;
                elseif app.explorerobj.calcsettings.selectedTool == 3
                    app.NetworkmappingButton.Value = 1;
                end
                enable_rules(app);
                %connectome
                if app.explorerobj.drawTool == 2
                    if ~isempty(app.explorerobj.calcsettings.fibfilt_connectome)
                        app.ConnectomeDropDown.Value = app.explorerobj.calcsettings.fibfilt_connectome;
                    end
                elseif app.explorerobj.drawTool == 3
                    if ~isempty(app.explorerobj.calcsettings.netmap_connectome)
                        app.ConnectomeDropDown.Value = app.explorerobj.calcsettings.netmap_connectome;
                    end
                end
                    
                %threshold
                app.ThresholdEditField.Value = app.explorerobj.calcsettings.calcthreshold;
                %resolution
                app.ResolutionDropDown.Value = app.explorerobj.calcsettings.resolution;
                %calculate in
                if app.explorerobj.calcsettings.calcspace == 1
                    app.CalculateinDropDown.Value = 'Template Space';
                elseif app.explorerobj.calcsettings.calcspace == 0
                    app.CalculateinDropDown.Value = 'Native Space';
                end
                %Fiberfiltering calculation method
                if app.explorerobj.calcsettings.calcmethod == 1
                    app.FiberfilteringCalculationMethodSwitch.Value = 'E-field Based Method';
                elseif app.explorerobj.calcsettings.calcmethod == 2
                    app.FiberfilteringCalculationMethodSwitch.Value = 'Fiber Based Method';
                end
                %biophysical model family
                if app.explorerobj.calcsettings.switch_connectivity == 1
                    if app.explorerobj.calcsettings.connectivity_type == 1
                        app.StimulationVolumesButton.Value = 1;
                    elseif app.explorerobj.calcsettings.connectivity_type == 2
                        app.PathwayActionModelingButton.Value = 1;
                    end
                end
            end

        end

        % Selection changed function: ToolsButtonGroup
        function ToolsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ToolsButtonGroup.SelectedObject;
            enable_rules(app);
           
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            selectedButton = app.ToolsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Sweetspot')
                selectedTool = 1;
            elseif strcmp(selectedButton.Text,'Fiberfiltering')
                selectedTool = 2;
            elseif strcmp(selectedButton.Text,'Networkmapping')
                selectedTool = 3;
            end
            app.explorerobj.calcsettings.selectedTool = selectedTool;
            if selectedTool == 2
                app.explorerobj.calcsettings.fibfilt_connectome = app.ConnectomeDropDown.Value;
                if exist(fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeDropDown.Value),'dir') %this is inside the dMRI_MultiTract folder
                    if exist(fullfile(ea_getconnectomebase('dmri'),app.ConnectomeDropDown.Value),'dir') %same connectome exists in both
                        warndlg("The same connectome exists in Multitract format as well as in Singletract format. Please set unique names before continuing. Further results will only reflect Singletract mode.");
                        app.explorerobj.calcsettings.multi_pathways = 0;
                    else
                        %if it only exists in the multitract pathway, then
                        %check once more for the multiple .mat files and
                        %set app.tractset.multi_pathways = 1;
                        mt_connectome = fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeDropDown.Value);
                        mt_connectome_dir = dir(fullfile(mt_connectome,'*.mat'));
                        mt_files = {mt_connectome_dir.name};
                        if length(mt_files) > 1
                            app.explorerobj.calcsettings.multi_pathways = 1;
                        else
                            app.explorerobj.calcsettings.multi_pathways = 0;
                        end
                    end
                else
                    app.explorerobj.calcsettings.multi_pathways = 0;
                end
            elseif selectedTool == 3
                app.explorerobj.calcsettings.netmap_connectome = app.ConnectomeDropDown.Value;
            end
            
            app.explorerobj.calcsettings.sweetspotresolution = app.SSResolutionEditField.Value;

            app.explorerobj.calcsettings.calcthreshold = app.ThresholdEditField.Value;
            app.explorerobj.calcsettings.space = app.CalculateinDropDown.Value;
            if strcmp(app.FiberfilteringCalculationMethodSwitch.Value,'E-field Based Method')
                app.explorerobj.calcsettings.calcmethod = 1;
                app.explorerobj.calcsettings.calcspace = 1;
            else
                app.explorerobj.calcsettings.calcmethod = 2;
                app.explorerobj.calcsettings.calcspace = 0;
            end

            if app.StimulationVolumesButton.Value
                app.explorerobj.calcsettings.connectivity_type = 1;
            elseif app.PathwayActionModelingButton.Value
                app.explorerobj.calcsettings.connectivity_type = 2;
            end
            setappdata(app.explorerobj.resultfig, 'explorer', app.explorerobj);
            app.CalculateButton.BackgroundColor = [1 1 1];
            % calculate general setup
            app.explorerobj.calculate;

            % save dataset:
            app.explorerobj.save;
            delete(app);
        end

        % Value changed function: FiberfilteringCalculationMethodSwitch
        function FiberfilteringCalculationMethodSwitchValueChanged(app, event)
            value = app.FiberfilteringCalculationMethodSwitch.Value;
            if strcmp(app.FiberfilteringCalculationMethodSwitch.Value,'E-field Based Method')
                app.CalculateinDropDown.Value = 'Template Space';
                app.CalculateinDropDown.Enable = 'on';
                app.explorerobj.calcsettings.calcspace = 1;
                app.explorerobj.calcsettings.calmethod = 1;
            elseif strcmp(app.FiberfilteringCalculationMethodSwitch.Value,'Fiber Based Method')
                app.CalculateinDropDown.Value = 'Native Space';
                app.CalculateinDropDown.Enable = 'on';
                app.explorerobj.calcsettings.calcspace = 0;
                app.explorerobj.calcsettings.calcmethod = 2;
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.explorerobj.calcsettings.calcthreshold = value; %check this!!!
        end

        % Selection changed function: BiophysicalModelFamilyButtonGroup
        function BiophysicalModelFamilyButtonGroupSelectionChanged(app, event)
            selectedButton = app.BiophysicalModelFamilyButtonGroup.SelectedObject;
            app.explorerobj.calcsettings.switch_connectivity = 1;
            if strcmp(selectedButton,'Stimulation Vol')
                app.explorerobj.calcsettings.connectivity_type = 1;
            elseif strcmp(selectedButton,'PAM')
                app.explorerobj.calcsettings.connectivity_type = 2;
            end
           %TODO - Highly interlinked with the rest of the stats button, so
           %you need to coordinate!
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            value = app.ResolutionDropDown.Value;
            app.explorerobj.calcsettings.resolution = value;
        end

        % Value changed function: CalculateinDropDown
        function CalculateinDropDownValueChanged(app, event)
            value = app.CalculateinDropDown.Value;
            if strcmp(app.CalculateinDropDown.Value,'Template Space')
                app.FiberfilteringCalculationMethodSwitch.Value = 'E-field Based Method';
            elseif strcmp(app.CalculateinDropDown.Value,'Native Space')
                app.FiberfilteringCalculationMethodSwitch.Value = 'Fiber Based Method';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculationSettingsPanel = uipanel(app.UIFigure);
            app.CalculationSettingsPanel.Title = 'Calculation Settings';
            app.SSResolutionEditFieldLabel = uilabel(app.CalculationSettingsPanel);
            app.SSResolutionEditFieldLabel.Text = 'Resolution';
            app.SSResolutionEditField = uieditfield(app.CalculationSettingsPanel, 'numeric');
            app.SSResolutionEditField.Value = 0.5;
            app.mmLabel = uilabel(app.CalculationSettingsPanel);
            app.mmLabel.Text = 'mm';
            app.ToolsButtonGroup = uibuttongroup(app.UIFigure);
            app.ToolsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToolsButtonGroupSelectionChanged, true);
            app.ToolsButtonGroup.Title = 'Tools';
            app.SweetspotButton = uiradiobutton(app.ToolsButtonGroup);
            app.SweetspotButton.Text = 'Sweetspot';
            app.SweetspotButton.Value = true;
            app.FiberfilteringButton = uiradiobutton(app.ToolsButtonGroup);
            app.FiberfilteringButton.Text = 'Fiberfiltering';
            app.NetworkmappingButton = uiradiobutton(app.ToolsButtonGroup);
            app.NetworkmappingButton.Text = 'Networkmapping';
            app.BiophysicalModelFamilyButtonGroup = uibuttongroup(app.UIFigure);
            app.BiophysicalModelFamilyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BiophysicalModelFamilyButtonGroupSelectionChanged, true);
            app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
            app.BiophysicalModelFamilyButtonGroup.Title = 'Biophysical Model Family';
            app.StimulationVolumesButton = uiradiobutton(app.BiophysicalModelFamilyButtonGroup);
            app.StimulationVolumesButton.Enable = 'off';
            app.StimulationVolumesButton.Text = 'Stimulation Volumes';
            app.StimulationVolumesButton.Value = true;
            app.PathwayActionModelingButton = uiradiobutton(app.BiophysicalModelFamilyButtonGroup);
            app.PathwayActionModelingButton.Enable = 'off';
            app.PathwayActionModelingButton.Text = 'Pathway Action Modeling';
            app.ConnectomeDropDown_2Label = uilabel(app.UIFigure);
            app.ConnectomeDropDown_2Label.Enable = 'off';
            app.ConnectomeDropDown_2Label.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.UIFigure);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.Enable = 'off';
            app.ConnectomeDropDown.Value = '';
            app.ThresholdEditField_3Label = uilabel(app.UIFigure);
            app.ThresholdEditField_3Label.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 200;
            app.VmLabel = uilabel(app.UIFigure);
            app.VmLabel.Text = 'V/m';
            app.ResolutionDropDownLabel = uilabel(app.UIFigure);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.UIFigure);
            app.ResolutionDropDown.Items = {'2 mm', '1 mm', '0.5 mm'};
            app.ResolutionDropDown.Editable = 'on';
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Enable = 'off';
            app.ResolutionDropDown.Value = '2 mm';
            app.CalculateinDropDownLabel = uilabel(app.UIFigure);
            app.CalculateinDropDownLabel.Enable = 'off';
            app.CalculateinDropDownLabel.Text = 'Calculate in...';
            app.CalculateinDropDown = uidropdown(app.UIFigure);
            app.CalculateinDropDown.Items = {'Template Space', 'Native Space'};
            app.CalculateinDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculateinDropDownValueChanged, true);
            app.CalculateinDropDown.Enable = 'off';
            app.CalculateinDropDown.Value = 'Template Space';
            app.FiberfilteringCalculationMethodSwitchLabel = uilabel(app.UIFigure);
            app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'off';
            app.FiberfilteringCalculationMethodSwitchLabel.Text = 'Fiberfiltering Calculation Method';
            app.FiberfilteringCalculationMethodSwitch = uiswitch(app.UIFigure, 'slider');
            app.FiberfilteringCalculationMethodSwitch.Items = {'E-field Based Method', 'Fiber Based Method'};
            app.FiberfilteringCalculationMethodSwitch.ValueChangedFcn = createCallbackFcn(app, @FiberfilteringCalculationMethodSwitchValueChanged, true);
            app.FiberfilteringCalculationMethodSwitch.Enable = 'off';
            app.FiberfilteringCalculationMethodSwitch.Value = 'E-field Based Method';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_calculate_unifiedmapping(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end