classdef ea_delete_pts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeleteSelectedPatientsUIFigure  matlab.ui.Figure
        StatusImage                     matlab.ui.control.Image
        DeleteSourcedata                matlab.ui.control.CheckBox
        DeleteRawdata                   matlab.ui.control.CheckBox
        DeleteDerivatives               matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        YesButton                       matlab.ui.control.Button
        PromptLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        derivatives
        rawdata
        sourcedata
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BIDSRoot, subjId)
            if ~iscell(subjId)
                subjId = {subjId};
            end
            app.derivatives = fullfile(BIDSRoot, 'derivatives', 'leaddbs', strcat('sub-', subjId));
            app.rawdata = fullfile(BIDSRoot, 'rawdata', strcat('sub-', subjId));
            app.sourcedata = fullfile(BIDSRoot, 'sourcedata', strcat('sub-', subjId));
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            ea_busyaction('on', app.StatusImage);

            if app.DeleteDerivatives.Value
                ea_delete(app.derivatives);
            end

            if app.DeleteRawdata.Value
                ea_delete(app.rawdata);
            end

            if app.DeleteSourcedata.Value
                ea_delete(app.sourcedata);
            end

            ea_busyaction('on', app.StatusImage);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Close request function: DeleteSelectedPatientsUIFigure
        function DeleteSelectedPatientsUIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeleteSelectedPatientsUIFigure = uifigure('Visible', 'off');
            app.DeleteSelectedPatientsUIFigure.Name = 'Delete Selected Patients';
            app.DeleteSelectedPatientsUIFigure.CloseRequestFcn = createCallbackFcn(app, @DeleteSelectedPatientsUIFigureCloseRequest, true);
            app.PromptLabel = uilabel(app.DeleteSelectedPatientsUIFigure);
            app.PromptLabel.Text = 'Are you sure you want to delete the selected patients?';
            app.YesButton = uibutton(app.DeleteSelectedPatientsUIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.CancelButton = uibutton(app.DeleteSelectedPatientsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DeleteDerivatives = uicheckbox(app.DeleteSelectedPatientsUIFigure);
            app.DeleteDerivatives.Text = 'Delete ''derivatives''';
            app.DeleteDerivatives.Value = true;
            app.DeleteRawdata = uicheckbox(app.DeleteSelectedPatientsUIFigure);
            app.DeleteRawdata.Text = 'Delete ''rawdata''';
            app.DeleteSourcedata = uicheckbox(app.DeleteSelectedPatientsUIFigure);
            app.DeleteSourcedata.Text = 'Delete ''sourcedata''';
            app.StatusImage = uiimage(app.DeleteSelectedPatientsUIFigure);
            app.StatusImage.Tag = 'busystatus';
            app.StatusImage.Visible = 'off';
            % Show the figure after all components are created
            app.DeleteSelectedPatientsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_delete_pts(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DeleteSelectedPatientsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DeleteSelectedPatientsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeleteSelectedPatientsUIFigure)
        end
    end
end