classdef lead_import < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LeadMigrateUIFigure          matlab.ui.Figure
        RunButton                    matlab.ui.control.Button
        ConverttoDicomusingDropDownLabel  matlab.ui.control.Label
        ConverttoDicomusingDropDown  matlab.ui.control.DropDown
        MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox  matlab.ui.control.CheckBox
        SelectpatientsButton         matlab.ui.control.Button
        ConvertDICOMdatatoBIDScompliantdatasetCheckBox  matlab.ui.control.CheckBox
        SelectoutputdatasetdirectoryButton  matlab.ui.control.Button
        BusyLamp                     matlab.ui.control.Lamp
        ContinueButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        source_files % Description
        output_files
        dicom_source_file
        input_label
        output_label
        flag
        close_flag
        handles
        options
        subjID = {};
        doOnlyRaw
    end
    
    methods (Access = public)
        function DropFcn(app, event, ~)
            switch event.DropType
                case 'file'
                    app.source_file = event.Data;
                case 'string'
                    app.source_file = {event.Data};
            end
            nonexist = cellfun(@(x) ~exist(x, 'dir'), patdir);
            if any(nonexist)
                fprintf('\nExcluded non-existent/invalid folder:\n');
                cellfun(@disp, patdir(nonexist));
                fprintf('\n');
                app.source_file(nonexist) = [];
            end
            enablerules(app)
        end
       function result = checkIfOneExist(app,cell_of_filenames,str_to_match)
            result = 0;
            %regexp returns an array of cells ({0} = does not match str,
            %{[1]} = matches the str).
            %isempty returns an array of 1 when the regexp is not
            %satisfied. Inverse of the cell fun will return 1 if the regexp
            %matches the str, and 0 if regexp does not match the str
            %any will check if there is atleast one pattern has matched
            if any(~cellfun('isempty',regexpi(cell_of_filenames,str_to_match)))
                result = 1;
            end
        end
        function result = checkIfNoneExist(app,cell_of_filenames,str_to_match)
           output = checkIfOneExist(app,cell_of_filenames,str_to_match);
           result = ~output;
        end
        function result = checkIfOnlyExist(app,cell_of_filenames,str_to_match)
            result = 0;
            if all(~cellfun('isempty',regexpi(cell_of_filenames,str_to_match)))
                result = 1;
            end
        end

        function result = enablerules(app,selection)                
               
               [filepath,filename,ext] = fileparts(selection);
               app.subjID{1} = regexprep(filename, '[\W_]', '');
               files_inside = dir_without_dots(selection);
               files_inside = {files_inside.name};
               %detection function: if the pat has derivatives and
               % raw data, but not in BIDS format then it should be
               % migrated.
               if checkIfOneExist(app,files_inside,'^glanat.nii$') && checkIfOneExist(app,files_inside,'anat.*.nii') && checkIfNoneExist(app,files_inside,'DICOM') && checkIfNoneExist(app,files_inside,'.*.dcm')
                   app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'off';
                   app.ConverttoDicomusingDropDown.Enable = 'off';
                   app.ConverttoDicomusingDropDownLabel.Enable = 'off';
                   app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                   app.flag = 'onlyMigrate';
                   app.doOnlyRaw = 0;
                   result = 1;
                   %only raw nifti available, no dicom is available. Migrate
                   %will be done, but only to move & rename the raw files
               elseif checkIfOnlyExist(app,files_inside,'.*.nii||.*.nii.gz') && ~any(ismember(files_inside,'ea_ui.mat'))
                   app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'off';
                   app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
                   app.ConverttoDicomusingDropDown.Enable = 'off';
                   app.ConverttoDicomusingDropDownLabel.Enable = 'off';
                   app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                   app.flag = 'onlyMigrate';
                   app.doOnlyRaw = 1;
                   result = 1;
                   %only DICOM files available
               elseif checkIfOnlyExist(app,files_inside,'dicom||.*.dcm')
                   %todo: remove support for .dcm files inside
                   app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
                   app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Enable = 'off';
                   app.ConverttoDicomusingDropDown.Enable = 'on';
                   app.ConverttoDicomusingDropDownLabel.Enable = 'on';
                   app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                   app.flag = 'onlyDCMconv';
                   app.doOnlyRaw = 0;
                   if isfolder(fullfile(selection,'DICOM'))
                       app.dicom_source_file{1} = fullfile(selection,'DICOM');
                   else
                       mkdir(fullfile(selection,'DICOM'))
                       for i=1:length(files_inside)
                           if regexp(files_inside{i},'.*.dcm')
                               movefile(fullfile(selection,files_inside{i}),fullfile(selection,'DICOM'))
                           end
                       end
                       app.dicom_source_file{1} = fullfile(selection,'DICOM');
                   end
                   result = 1;
                   %BIDS Compliant raw data nifti files are available! in this case,
                   %dcm -> bids conversion should require special
                   %handling. Only the conversion from nifti -> lead bids
                   %will be performed
               elseif any(ismember(files_inside,'rawdata'))
                   app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
                   app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Enable = 'off';
                   app.ConverttoDicomusingDropDown.Enable = 'on';
                   app.ConverttoDicomusingDropDownLabel.Enable = 'on';
                   app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'on';
                   app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                   app.flag = 'onlyDCMconv';
                   app.doOnlyRaw = 0;
                   %both dicom & derivatives available. Requires special
                   %handling in the migrate code.
                   result = 1;
               elseif any(ismember(files_inside,'ea_ui.mat')) && checkIfOneExist(app,files_inside,'^glanat*.nii$') && checkIfOneExist(app,files_inside,'dicom||.*.dcm')
                   app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                   app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                   app.ConverttoDicomusingDropDown.Enable = 'on';
                   app.ConverttoDicomusingDropDownLabel.Enable = 'on';
                   app.flag = 'MigrateDCMconv';
                   app.doOnlyRaw = 0;
                   if isfolder(fullfile(selection,'DICOM'))
                       app.dicom_source_file{1} = fullfile(selection,'DICOM');
                   else
                       mkdir(fullfile(selection,'DICOM'))
                       for i=1:length(files_inside)
                           if regexp(files_inside{i},'.*.dcm')
                               movefile(fullfile(selection,files_inside{i}),fullfile(selection,'DICOM'))
                           end
                       end
                       app.dicom_source_file{1} = fullfile(selection,'DICOM');
                   end
                   result = 1;
               elseif checkIfOneExist(app,files_inside,'.*.nii||.*.nii.gz') && checkIfOneExist(app,files_inside,'dicom||.*.dcm') && ~all(ismember(files_inside,'ea_ui.mat')) %raw nifti + dicom
                   app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                   app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                   app.ConverttoDicomusingDropDown.Enable = 'on';
                   app.ConverttoDicomusingDropDownLabel.Enable = 'on';
                   app.flag = 'MigrateDCMconv';
                   app.doOnlyRaw = 1;
                   if isfolder(fullfile(selection,'DICOM'))
                       app.dicom_source_file{1} = fullfile(selection,'DICOM');
                   else
                       mkdir(fullfile(selection,'DICOM'))
                       for i=1:length(files_inside)
                           if regexp(files_inside{i},'.*.dcm')
                               movefile(fullfile(selection,files_inside{i}),fullfile(selection,'DICOM'))
                           end
                       end
                       app.dicom_source_file{1} = fullfile(selection,'DICOM');
                   end
               else
                   msgbox("Unable to detect the type of processing required, please select options manually..");
                   result = 0;
                   return
               end
        end
        function results = op_label(app,flag,source_pt)
            if strcmp(flag,'source')
                app.SelectpatientsButton.Text = source_pt;
                app.SelectpatientsButton.FontColor = 'g';
            end
            if strcmp(flag,'multsource')
                app.SelectpatientsButton.Text = ['Loaded Multiple Patients(' num2str(length(app.source_files)) ')'];
                app.SelectpatientsButton.FontColor = 'g';
                app.SelectpatientsButton.FontColor = 'g';
                clear Label;
                Label = uilabel(app.LeadMigrateUIFigure,'Text','Loaded multiple patients, Enabled components is specific for the first subject only','Position',[100,189,450,22]);
            end
            if strcmp(flag,'dest')
                app.SelectoutputdatasetdirectoryButton.Text = app.output_files{1};
                app.SelectoutputdatasetdirectoryButton.FontColor = 'g';
            end
        
        end
        
        function results = reset_rules(app)
            app.doOnlyRaw = 0;
            app.flag = '';
            app.ConverttoDicomusingDropDown.Enable = 'on';
            app.ConverttoDicomusingDropDownLabel.Enable = 'on';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'on';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Enable = 'on';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
            app.SelectpatientsButton.Text = '';
            
        end
        
        function assign_flag(app)
            dcmConv =  app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
            doMigrate = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
            if dcmConv && ~doMigrate
                app.flag = 'onlyDCMconv';
            elseif ~dcmConv && doMigrate
                app.flag = 'onlyMigrate';
            elseif dcmConv && doMigrate
                app.flag = 'MigrateDCMconv';
            end
        end
        function [bidsroot,subjID] = generateopparams(app,dest)
            bidsroot = dest;
            subjID = app.subjID;
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, source_files, options, handles)
            app.LeadMigrateUIFigure.Name = 'Lead Import';
            app.LeadMigrateUIFigure.HandleVisibility = 'on';
            app.SelectpatientsButton.FontColor = 'r';
            app.ContinueButton.Visible = 'off';
            if exist('source_files','var')
               app.source_files = source_files;
               if length(app.source_files) > 1
                    op_label(app,'multsource');
               else
                   op_label(app,'source',app.source_files{1})
               end
               enablerules(app,app.source_files{1});
               app.close_flag = 1;    
           end
           if exist('handles','var')
               app.handles = handles;
               if getappdata(app.handles.leadfigure, 'BIDSRoot')
                   if contains(app.source_files{1},'sourcedata')
                        app.output_files = {getappdata(app.handles.leadfigure, 'BIDSRoot')};
                        op_label(app,'dest');
                        app.close_flag = 1;
                        app.flag = 'onlyDCMconv';
                        app.RunButton.Enable = 'on';
                   end
               else
                   app.SelectoutputdatasetdirectoryButton.FontColor = 'r';
               end
           end
           if exist('options','var')
               app.options = options;
           end

        end

        % Button pushed function: SelectpatientsButton
        function SelectpatientsButtonPushed(app, event)
            app.source_files = ea_uigetdir(pwd);
            drawnow;
            figure(app.LeadMigrateUIFigure);
            reset_rules(app)
            if length(app.source_files) > 1
                op_label(app,'multsource');
            else
                op_label(app,'source',app.source_files{1})
            end
            
            enablerules(app,app.source_files{1});
            app.BusyLamp.Color = 'green';
        end

        % Value changed function: 
        % ConvertDICOMdatatoBIDScompliantdatasetCheckBox
        function ConvertDICOMdatatoBIDScompliantdatasetCheckBoxValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            value = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
            assign_flag(app)
            
            app.BusyLamp.Color = 'green';
        end

        % Value changed function: ConverttoDicomusingDropDown
        function ConverttoDicomusingDropDownValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            value = app.ConverttoDicomusingDropDown.Value;
            assign_flag(app)
            
            app.BusyLamp.Color = 'green';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            app.BusyLamp.Color = 'red';
            %source = app.source_files;
            if isempty(app.output_files)
               errordlg("No output directory selected, select to continue...",'Output directory error')
            else
                dicom_conv = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
                doMigrate = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
                if isempty(app.doOnlyRaw)
                    app.doOnlyRaw = 0;
                end
                dest = app.output_files;
                for pt = 1:length(app.source_files)
                    if pt >= 2
                        reset_rules(app)
                        result = enablerules(app,app.source_files{pt});
                        op_label(app,'source',app.source_files{pt});
                        if ~result %enablerules failed
                            continue
                        else 
                            if app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value ~= dicom_conv
                                if app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                                    app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = dicom_conv;
                                else
                                    disp("No dicom files available")
                                end
                            end
                            if app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value ~= doMigrate
                                if app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value
                                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = doMigrate;
                                else
                                    disp("No files available for Migration")
                                end
                            end
                        end
                        %reset flags for the new rules
                        dicom_conv = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
                        doMigrate = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
                        assign_flag(app)
                    end
                    
                    source_dir = {app.source_files{pt}};
                    convert_using = app.ConverttoDicomusingDropDown.Value;
                    if strcmp(app.flag,'onlyMigrate')
                        f = waitbar(0,'Migrating dataset..');
                        waitbar(0.33,f,'Migrating dataset...');
                        if dicom_conv && ~isempty(app.dicom_source_file) && ~app.doOnlyRaw
                            ea_legacy2bids(source_dir,dest,1,app.dicom_source_file,1,0);
                        elseif ~dicom_conv && doMigrate && app.doOnlyRaw
                            ea_legacy2bids(source_dir,dest,0,'',0,1);
                        elseif doMigrate && ~dicom_conv && ~app.doOnlyRaw
                            ea_legacy2bids(source_dir,dest,0,'',0,0);
                        end
                        waitbar(1,f,'Done!');
                        close(f);
                        % after migration is done, trigger ea_dataset_import.m
                        % without DICOM conversion to sort out .nii.gz files in BIDS
                        % dataset
                        ea_dataset_import(source_dir, [], [], 0)
                        
                    end
                    %both the migrate and the dcm code here. Migrate runs first to
                    %dump the legacy raw dataset into the new folder, "legacy_rawdata"
                    if strcmp(app.flag,'MigrateDCMconv')
                        %app.dicom_source: parent dicom,
                        %implement checkpoint if folder is not empty
                        if app.doOnlyRaw && dicom_conv
                            ea_legacy2bids(source_dir,dest,1,app.dicom_source_file,1,1);
                        elseif dicom_conv && ~app.doOnlyRaw
                            ea_legacy2bids(source_dir,dest,1,app.dicom_source_file,1,0)
                        end
                        ea_dataset_import(source_dir, dest, convert_using, 1);
                    end
                    % only DICOM conversion, no migration
                    if strcmp(app.flag,'onlyDCMconv')
                        ea_dataset_import(source_dir, dest, convert_using, 1);
                    end
                    app.BusyLamp.Color = 'green';
                    
                    % if running automatically, close as soon as the migration
                    % is over.
                end
                if app.close_flag
                   ea_busyaction('on',app.handles.leadfigure,'dbs');
                   setappdata(app.handles.leadfigure, 'BIDSRoot', dest{1});
                   setappdata(app.handles.leadfigure, 'subjID', app.subjID);
                   app.delete;
                   %close(app.LeadMigrateUIFigure);
                end
            end
        end

        % Callback function
        function MigrateOptionsButtonGroupSelectionChanged(app, event)
           
        end

        % Value changed function: 
        % MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox
        function MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged(app, event)
            value = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
            if value && ~app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'onlyMigrate';
            elseif value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'MigrateDCMconv';
            elseif ~value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag =  'onlyDCMconv';
            end
        end

        % Button pushed function: SelectoutputdatasetdirectoryButton
        function SelectoutputdatasetdirectoryButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            app.output_files = {uigetdir()};
            drawnow;
            figure(app.LeadMigrateUIFigure);
            op_label(app,'dest');
            output_message = app.output_files{1};
            
            if isempty(app.output_label)
                app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",output_message,"Position",[37,290,250,22]);
            else
                app.output_label.Label.Text = '';
                app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",output_message,"Position",[37,290,250,22]);
            end
            app.SelectoutputdatasetdirectoryButton.FontColor = 'g';
            app.SelectoutputdatasetdirectoryButton.FontWeight = 'bold';
            %op_status.Label = uilabel(app.LeadMigrateUIFigure,"Text",'Loaded Output Directory(s)!',"Position",[420,290,250,22]);
            if app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value && ~app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'onlyMigrate';
            elseif app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'MigrateDCMconv';
            elseif ~app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'onlyDCMconv';
            end
            if ~isempty(app.source_files) && ~isempty(app.output_files)
                app.RunButton.Enable = 'on';
            end
            app.BusyLamp.Color = 'green';
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            dicom_conv = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
            doMigrate = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
            assign_flag(app)        
            source_dir = {app.source_files{pt}};
            convert_using = app.ConverttoDicomusingDropDown.Value;
            if strcmp(app.flag,'onlyMigrate')
                f = waitbar(0,'Migrating dataset..');
                waitbar(0.33,f,'Migrating dataset...');
                if dicom_conv && ~isempty(app.dicom_source_file) && ~app.doOnlyRaw
                    ea_legacy2bids(source_dir,dest,1,app.dicom_source_file,1,0);
                elseif ~dicom_conv && doMigrate && app.doOnlyRaw
                    ea_legacy2bids(source_dir,dest,0,'',0,1);
                elseif doMigrate && ~dicom_conv && ~app.doOnlyRaw
                    ea_legacy2bids(source_dir,dest,0,'',0,0);
                end
                waitbar(1,f,'Done!');
                close(f);
                % after migration is done, trigger ea_dataset_import.m
                % without DICOM conversion to sort out .nii.gz files in BIDS
                % dataset
                ea_dataset_import(source_dir, [], [], 0)
                
            end
            %both the migrate and the dcm code here. Migrate runs first to
            %dump the legacy raw dataset into the new folder, "legacy_rawdata"
            if strcmp(app.flag,'MigrateDCMconv')
                %app.dicom_source: parent dicom,
                %implement checkpoint if folder is not empty
                if app.doOnlyRaw && dicom_conv
                    ea_legacy2bids(source_dir,dest,1,app.dicom_source_file,1,1);
                elseif dicom_conv && ~app.doOnlyRaw
                    ea_legacy2bids(source_dir,dest,1,app.dicom_source_file,1,0)
                end
                ea_dataset_import(source_dir, dest, convert_using, 1);
            end
            % only DICOM conversion, no migration
            if strcmp(app.flag,'onlyDCMconv')
                ea_dataset_import(source_dir, dest, convert_using, 1);
            end
            app.BusyLamp.Color = 'green';
            
            % if running automatically, close as soon as the migration
            % is over.
            if app.close_flag
                ea_busyaction('on',app.handles.leadfigure,'dbs');
                setappdata(app.handles.leadfigure, 'BIDSRoot', dest{1});
                setappdata(app.handles.leadfigure, 'subjID', app.subjID);
                app.delete;
                %close(app.LeadMigrateUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LeadMigrateUIFigure = uifigure('Visible', 'off');
            app.LeadMigrateUIFigure.Name = 'Lead Migrate';
            app.RunButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ConverttoDicomusingDropDownLabel = uilabel(app.LeadMigrateUIFigure);
            app.ConverttoDicomusingDropDownLabel.Text = 'Convert to Dicom using..';
            app.ConverttoDicomusingDropDown = uidropdown(app.LeadMigrateUIFigure);
            app.ConverttoDicomusingDropDown.Items = {'dcm2niix', 'dicm2nii', 'SPM'};
            app.ConverttoDicomusingDropDown.ValueChangedFcn = createCallbackFcn(app, @ConverttoDicomusingDropDownValueChanged, true);
            app.ConverttoDicomusingDropDown.Value = 'dcm2niix';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox = uicheckbox(app.LeadMigrateUIFigure);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged, true);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Text = 'Migrate legacy Lead-DBS dataset to BIDS dataset';
            app.SelectpatientsButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.SelectpatientsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpatientsButtonPushed, true);
            app.SelectpatientsButton.Text = 'Select patient(s)';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox = uicheckbox(app.LeadMigrateUIFigure);
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConvertDICOMdatatoBIDScompliantdatasetCheckBoxValueChanged, true);
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Text = 'Convert DICOM data to BIDS compliant dataset';
            app.SelectoutputdatasetdirectoryButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.SelectoutputdatasetdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputdatasetdirectoryButtonPushed, true);
            app.SelectoutputdatasetdirectoryButton.Text = 'Select output dataset directory';
            app.BusyLamp = uilamp(app.LeadMigrateUIFigure);
            app.ContinueButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.LeadMigrateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lead_import(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LeadMigrateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LeadMigrateUIFigure)
        end
    end
end